classdef OceanFluxBudget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OceanFluxBudgetToolUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ResourcesPanel                  matlab.ui.container.Panel
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        ComponentPanel                  matlab.ui.container.Panel
        ComponentDropDown               matlab.ui.control.DropDown
        DisplayOptionsPanel             matlab.ui.container.Panel
        ShowcontrolvolumecomponentsSwitch  matlab.ui.control.Switch
        ShowbudgetcomponentsLabel       matlab.ui.control.Label
        FixaxesSwitch                   matlab.ui.control.Switch
        FixaxesSwitchLabel              matlab.ui.control.Label
        ShowtrendSwitch                 matlab.ui.control.Switch
        ShowmaxminSwitch_2Label_2       matlab.ui.control.Label
        ShowmaxminSwitch                matlab.ui.control.Switch
        ShowmaxminSwitch_2Label         matlab.ui.control.Label
        ReferenceValuesPanel            matlab.ui.container.Panel
        ReferenceTemperatureCSpinner    matlab.ui.control.Spinner
        ReferenceTemperatureCSpinnerLabel  matlab.ui.control.Label
        ReferenceSalinitygkgSpinner     matlab.ui.control.Spinner
        ReferenceSalinitygkgSpinnerLabel  matlab.ui.control.Label
        PropertyTimeseriesPanel         matlab.ui.container.Panel
        BalancecontrolvolumemassSwitch  matlab.ui.control.Switch
        BalancecontrolvolumemassLabel   matlab.ui.control.Label
        ReinitializeButton              matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        ComponentLabel                  matlab.ui.control.Label
        CorrelationTable                matlab.ui.control.Table
        FluxesLabel                     matlab.ui.control.Label
        PropertiesLabel                 matlab.ui.control.Label
        SaltFluxFigure                  matlab.ui.control.UIAxes
        TemperatureFigure               matlab.ui.control.UIAxes
        MassFluxFigure                  matlab.ui.control.UIAxes
        HeatFluxFigure                  matlab.ui.control.UIAxes
        VolFluxFigure                   matlab.ui.control.UIAxes
        LFCFluxFigure                   matlab.ui.control.UIAxes
        DensityFigure                   matlab.ui.control.UIAxes
        SalinityFigure                  matlab.ui.control.UIAxes
        SpeedFigure                     matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        TotalControlVolumeLabel         matlab.ui.control.Label
        PropertiesLabel_2               matlab.ui.control.Label
        BudgetAnomaliesLabel            matlab.ui.control.Label
        SaltBudgetFigure                matlab.ui.control.UIAxes
        MassBudgetFigure                matlab.ui.control.UIAxes
        AvgTemperatureFigure            matlab.ui.control.UIAxes
        AvgSalinityFigure               matlab.ui.control.UIAxes
        LFCBudgetFigure                 matlab.ui.control.UIAxes
        HeatBudgetFigure                matlab.ui.control.UIAxes
        VolBudgetFigure                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        Components      % Structure containing the components structure.
        budgets         % Structure containing the budgets structure.
        CtrlVol         % Structure containing the CtrlVol structure.
        DataParams      % Structure containing the DataParams structure.
        FluxParams      % Structure containing the FluxParams structure.
        PlotParams      % Structure containing the PlotParams structure.
        this_component  % Name of component being displayed.
    end

    methods (Access = private)
        function UpdateComponentPropertiesFigure(app)
            if(strcmp(app.Components.(app.this_component).type,'advective'))        % Plot this component's raw timeseries
                app.PropertiesLabel.Visible   = 'on' ;
                app.SpeedFigure.Visible       = 'on' ;
                app.TemperatureFigure.Visible = 'on' ;
                app.SalinityFigure.Visible    = 'on' ;
                app.DensityFigure.Visible     = 'on' ;
                plot(app.SpeedFigure,      app.DataParams.time,app.Components.(app.this_component).normal_speed,'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
                plot(app.TemperatureFigure,app.DataParams.time,app.Components.(app.this_component).temperature, 'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
                plot(app.SalinityFigure,   app.DataParams.time,app.Components.(app.this_component).salinity,    'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
                plot(app.DensityFigure,    app.DataParams.time,app.Components.(app.this_component).density,     'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            else            % Hide advective figure panels for this non-advective component.

                app.PropertiesLabel.Visible            = 'off' ;
                app.SpeedFigure.Visible                = 'off' ;
                app.SpeedFigure.Children.Visible       = 'off' ;
                app.TemperatureFigure.Visible          = 'off' ;
                app.TemperatureFigure.Children.Visible = 'off' ;
                app.SalinityFigure.Visible             = 'off' ;
                app.SalinityFigure.Children.Visible    = 'off' ;
                app.DensityFigure.Visible              = 'off' ;
                app.DensityFigure.Children.Visible     = 'off' ;
            end % if
        end

        function UpdateFluxesFigure(app)

            plotFluxesFigure(app,'MassFluxFigure','mass_flux',1e9) ;
            plotFluxesFigure(app,'VolFluxFigure','vol_flux',1e6) ;
            plotFluxesFigure(app,'SaltFluxFigure','salt_flux',1e6) ;
            plotFluxesFigure(app,'LFCFluxFigure','LFC_flux',1e9/app.DataParams.secs_in_a_yr) ;
            plotFluxesFigure(app,'HeatFluxFigure','heat_flux',1e12) ;

            function plotFluxesFigure(app,han,var,fac)
                if(strcmp(app.Components.(app.this_component).type,'advective') || strcmp(var,'heat_flux'))        % Plot this component's computed timeseries
                    plot(app.(han),app.DataParams.time,app.Components.(app.this_component).(var)./fac,'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
                    app.(han).Visible = 'on' ;
                    if(strcmp(app.Components.(app.this_component).type,'advective'))
                        % Add correlation numbers
                        app.CorrelationTable.Data = [ ...
                            app.Components.(app.this_component).heat_flux_correl.vol_flux.r app.Components.(app.this_component).heat_flux_correl.temperature.r ; ...
                            app.Components.(app.this_component).LFC_flux_correl.vol_flux.r  app.Components.(app.this_component).LFC_flux_correl.salinity.r ; ...
                            app.Components.(app.this_component).salt_flux_correl.vol_flux.r app.Components.(app.this_component).salt_flux_correl.salinity.r    ] ;
                    end % if
                else            % Hide advective figure panels for this non-advective component.
                    app.(han).Visible = 'off' ;
                    for hh = 1:numel(app.(han).Children)
                        app.(han).Children(hh).Visible = 'off' ;
                    end % hh
                end % if

                % Add maximum/minimum lines to plot
                if(strcmp(app.PlotParams.extremaFlag,'On'))
                    if(strcmp(app.Components.(app.this_component).type,'advective') || strcmp(var,'heat_flux'))
                        xdata = app.(han).Children(end).XData ;
                        ydata = app.(han).Children(end).YData ;
                        [~,max_ind] = max(ydata) ;
                        [~,min_ind] = min(ydata) ;
                        ylimits = app.(han).YLim ;
                        hold(app.(han),'On') ;
                        plot(app.(han),[xdata(max_ind) xdata(max_ind)],ylimits,'-','Color','red' ,'DisplayName','Max.','LineWidth',2) ;
                        plot(app.(han),[xdata(min_ind) xdata(min_ind)],ylimits,'-','Color','blue','DisplayName','Min,','LineWidth',2) ;
                        app.(han).YLim = ylimits ;
                        hold(app.(han),'Off') ;
                    end % if
                end % if

                % Add trend lines to plot
                if(strcmp(app.PlotParams.trendFlag,'On'))
                    if(strcmp(app.Components.(app.this_component).type,'advective') || strcmp(var,'heat_flux'))
                        xdata = app.(han).Children(end).XData ;
                        ydata = app.(han).Children(end).YData ;
                        hold(app.(han),'On') ;
                        if(ydata(end) >= ydata(1))
                            trend_color = 'red' ;
                        else
                            trend_color = 'blue' ;
                        end % if
                        plot(app.(han),[xdata(1) xdata(end)],[ydata(1) ydata(end)],'--','Color',trend_color,'DisplayName','Trend','LineWidth',3) ;
                        hold(app.(han),'Off') ;
                    end % if
                end % if
            end
        end

        function UpdateBudgetsFigure(app)
            % Plot budget computed timeseries
            plotBudgetFigure(app,'VolBudgetFigure','volume',1e9) ;
            plotBudgetFigure(app,'MassBudgetFigure','mass',1e18) ;
            plotBudgetFigure(app,'HeatBudgetFigure','heat',1e21) ;
            plotBudgetFigure(app,'SaltBudgetFigure','salt',1e15) ;
            plotBudgetFigure(app,'LFCBudgetFigure','LFC',1e9) ;

            function plotBudgetFigure(app,han,var,fac)
                budget_names = fieldnames(app.budgets.(var)) ;
                Ns = numel(budget_names) ;
                l = zeros(1,Ns) ;
                l(1) = plot(app.(han),app.DataParams.time,app.budgets.(var).total./fac,'LineWidth',3,'Color','g','DisplayName','Total') ;
                tmp = get(l(1)) ;
                tmp.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Total','') ;

                % Plot individual components, if required
                if(strcmp(app.PlotParams.BudgetComponentsFlag,'On'))
                    hold(app.(han),'on') ;
                    for ss = 2:Ns
                        l(ss) = plot(app.(han),app.DataParams.time,app.budgets.(var).(budget_names{ss})./fac,...
                            'LineWidth',2,'Color',app.PlotParams.(budget_names{ss}).color,'DisplayName',app.Components.(budget_names{ss}).name) ;
                        tmp = get(l(ss)) ;
                        tmp.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(app.Components.(budget_names{ss}).name,'') ;
                    end % ss
                    hold(app.(han),'off') ;
                    if(strcmp(var,'heat'))
                        tmp = app.(han).Position ;
                        leg_h = legend(app.(han),l,'location','eastoutside','NumColumns',1);
                        tmp2 = leg_h.Position ;
                        leg_h.Position = [tmp2(1)+0.35 tmp2(2)+0.18 tmp2(3:4)] ;
                        app.(han).Position = tmp ;
                    end % if
                else
                    legend(app.(han),'Off') ;
                end % if
            end
        end

        function UpdateCtrlVolPropertiesFigure(app)

            plotCtrlVolFigure(app,'AvgTemperatureFigure','temperature') ;
            plotCtrlVolFigure(app,'AvgSalinityFigure','salinity') ;

            function plotCtrlVolFigure(app,han,var)

                component_names = fieldnames(app.Components) ;
                Ns = numel(component_names) ;
                l = zeros(1,Ns) ;
                l(1) = plot(app.(han),app.DataParams.time,app.CtrlVol.(var), 'LineWidth',3,'Color','g','DisplayName','Total') ;
                tmp = get(l(1)) ;
                tmp.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Total','') ;

                % Plot individual components, if required
                if(strcmp(app.PlotParams.BudgetComponentsFlag,'On'))
                    hold(app.(han),'on') ;
                    for ss = 1:Ns
                        try         % Not all Components have all variables
                            l(ss+1) = plot(app.(han),app.DataParams.time,app.Components.(component_names{ss}).(var),...
                                'LineWidth',2,'Color',app.PlotParams.(component_names{ss}).color,'DisplayName',app.Components.(component_names{ss}).name) ;
                            tmp = get(l(ss+1)) ;
                            tmp.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(app.Components.(component_names{ss}).name,'') ;
                        catch
                            % fprintf(1,' Variable [%s] for component [%s] doesn''t exist.\n',var,component_names{ss}) ;
                        end % try
                    end % ss
                    hold(app.(han),'off') ;
                end % if
            end
        end

        function UpdateAll(app)
            app.Components = UpdateComponents(app.FluxParams,app.DataParams,app.Components) ;
            app.budgets    = ComputeBudgets(app.Components,app.DataParams) ;
            app.CtrlVol    = ComputeCtrlVolProperties(app.budgets,app.DataParams) ;

            UpdateComponentPropertiesFigure(app) ;
            UpdateFluxesFigure(app) ;
            UpdateBudgetsFigure(app) ;
            UpdateCtrlVolPropertiesFigure(app) ;

            names = fieldnames(app) ;
            inds = find(contains(names,'Figure')) ;
            for ii = 2:length(inds)
                AdjustFigure(app,names{inds(ii)}) ;
            end % ii

            % Update correlations table
            if(strcmp(app.Components.(app.this_component).type,'advective'))
                app.CorrelationTable.Data = [ ...
                    app.Components.(app.this_component).heat_flux_correl.vol_flux.r app.Components.(app.this_component).heat_flux_correl.temperature.r ; ...
                    app.Components.(app.this_component).salt_flux_correl.vol_flux.r app.Components.(app.this_component).salt_flux_correl.salinity.r ; ...
                    app.Components.(app.this_component).LFC_flux_correl.vol_flux.r  app.Components.(app.this_component).LFC_flux_correl.salinity.r ] ;

                tmp = [ ...
                    app.Components.(app.this_component).heat_flux_correl.vol_flux.p app.Components.(app.this_component).heat_flux_correl.temperature.p ; ...
                    app.Components.(app.this_component).salt_flux_correl.vol_flux.p app.Components.(app.this_component).salt_flux_correl.salinity.p ; ...
                    app.Components.(app.this_component).LFC_flux_correl.vol_flux.p  app.Components.(app.this_component).LFC_flux_correl.salinity.p    ] ;
                addStyle(app.CorrelationTable,uistyle('Icon','error','IconAlignment','right')) ;
                [row,col] = find(tmp < app.PlotParams.correlationTable.significance) ;
                addStyle(app.CorrelationTable,uistyle('Icon','success','IconAlignment','right'),'cell',[row,col]) ;
                app.CorrelationTable.Visible   = 'On';
            else
                app.CorrelationTable.Visible   = 'Off';
            end % if
        end

        function StartPlots(app,han)
            app.(han).PositionConstraint = 'innerposition';
            tmp = app.(han).InnerPosition ;
            app.(han).InnerPosition = [tmp(1:2) app.PlotParams.width app.PlotParams.height] ;
            app.(han).XGrid              = 'On' ;
            app.(han).YGrid              = 'On' ;
            app.(han).Box                = 'On' ;
        end

        function AdjustFigure(app,han)
            app.(han).XLim = [app.DataParams.start_date app.DataParams.end_date] ;

            % Let axes auto-adjust, or fix them to default values
            if(strcmp(app.PlotParams.FixaxesFlag,'Off'))
                app.(han).YLimMode = 'auto' ;
            else
                app.(han).YLimMode = 'manual' ;
            end % if

            % % Add maximum/minimum lines to plot
            % if(strcmp(app.PlotParams.extremaFlag,'On') && contains(han,'FluxFigure'))
            %     xdata = app.(han).Children(end).XData ;
            %     ydata = app.(han).Children(end).YData ;
            %     [~,max_ind] = max(ydata) ;
            %     [~,min_ind] = min(ydata) ;
            %     ylimits = app.(han).YLim ;
            %     hold(app.(han),'On') ;
            %     plot(app.(han),[xdata(max_ind) xdata(max_ind)],ylimits,'-','Color','red' ,'DisplayName','Max.','LineWidth',2) ;
            %     plot(app.(han),[xdata(min_ind) xdata(min_ind)],ylimits,'-','Color','blue','DisplayName','Min,','LineWidth',2) ;
            %     app.(han).YLim = ylimits ;
            %     hold(app.(han),'Off') ;
            % end % if
            %
            % % Add trend lines to plot
            % if(strcmp(app.PlotParams.trendFlag,'On') && contains(han,'FluxFigure'))
            %     xdata = app.(han).Children(end).XData ;
            %     ydata = app.(han).Children(end).YData ;
            %     hold(app.(han),'On') ;
            %     if(ydata(end) >= ydata(1))
            %         trend_color = 'red' ;
            %     else
            %         trend_color = 'blue' ;
            %     end % if
            %     plot(app.(han),[xdata(1) xdata(end)],[ydata(1) ydata(end)],'--','Color',trend_color,'DisplayName','Trend','LineWidth',3) ;
            %     hold(app.(han),'Off') ;
            % end % if

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            rng(1) ;    % Random number generator: This makes random numbers reproducible.
            app.this_component  = 'WestFramStrait' ;
            app.DataParams      = InitializeDataParameters(30) ;
            app.FluxParams      = InitializeFluxParameters ;
            app.Components      = InitializeComponents(app.DataParams,app.FluxParams) ;
            app.PlotParams      = InitializePlotParameters ;

            addStyle(app.CorrelationTable,uistyle('HorizontalAlignment','center'));
            app.PlotParams.correlationTable.significance = 1e-3 ;           % Level of correlation significance set here.

            names = fieldnames(app) ;
            inds = find(contains(names,'Figure')) ;
            for ii = 2:length(inds)
                StartPlots(app,names{inds(ii)}) ;
            end % ii

            names = app.ComponentDropDown.ItemsData ;
            for ii = 1:length(names)
                s = uistyle(FontColor = app.PlotParams.(names{ii}).color) ;
                addStyle(app.ComponentDropDown,s,'item',ii)
            end % ii
            app.ComponentLabel.FontColor  = app.PlotParams.(app.this_component).color ;
            app.ComponentLabel.Text       = app.Components.(app.this_component).name ;

            UpdateAll(app) ;
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            app.Components             = InitializeComponents(app.DataParams,app.FluxParams) ;
            app.PlotParams.FixaxesFlag = 'Off';
            app.FixaxesSwitch.Value    = 'Off';
            UpdateAll(app) ;
        end

        % Value changed function: ComponentDropDown
        function ComponentDropDownValueChanged(app, event)
            app.this_component              = app.ComponentDropDown.Value;
            app.PlotParams.FixaxesFlag      = 'Off';
            app.FixaxesSwitch.Value         = 'Off';
            app.ComponentDropDown.FontColor = app.PlotParams.(app.this_component).color ;
            app.ComponentLabel.FontColor    = app.PlotParams.(app.this_component).color ;
            app.ComponentLabel.Text         = app.Components.(app.this_component).name ;
            UpdateAll(app) ;
        end

        % Value changed function: BalancecontrolvolumemassSwitch
        function BalancecontrolvolumemassSwitchValueChanged(app, event)
            app.DataParams.massBalance = app.BalancecontrolvolumemassSwitch.Value;
            app.Components             = InitializeComponents(app.DataParams,app.FluxParams) ;
            app.PlotParams.FixaxesFlag = 'Off';
            app.FixaxesSwitch.Value    = 'Off';
            UpdateAll(app) ;
        end

        % Value changed function: ReferenceTemperatureCSpinner
        function ReferenceTemperatureCSpinnerValueChanged(app, event)
            app.FluxParams.T_ref = app.ReferenceTemperatureCSpinner.Value;
            UpdateAll(app) ;
        end

        % Value changing function: ReferenceTemperatureCSpinner
        function ReferenceTemperatureCSpinnerValueChanging(app, event)
            app.FluxParams.T_ref = event.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ReferenceSalinitygkgSpinner
        function ReferenceSalinitygkgSpinnerValueChanged(app, event)
            app.FluxParams.S_ref = app.ReferenceSalinitygkgSpinner.Value;
            UpdateAll(app) ;
        end

        % Value changing function: ReferenceSalinitygkgSpinner
        function ReferenceSalinitygkgSpinnerValueChanging(app, event)
            app.FluxParams.S_ref = event.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ShowmaxminSwitch
        function ShowmaxminSwitchValueChanged(app, event)
            app.PlotParams.extremaFlag = app.ShowmaxminSwitch.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ShowtrendSwitch
        function ShowtrendSwitchValueChanged(app, event)
            app.PlotParams.trendFlag = app.ShowtrendSwitch.Value;
            UpdateAll(app) ;
        end

        % Value changed function: FixaxesSwitch
        function FixaxesSwitchValueChanged(app, event)
            app.PlotParams.FixaxesFlag = app.FixaxesSwitch.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ShowcontrolvolumecomponentsSwitch
        function ShowcontrolvolumecomponentsSwitchValueChanged(app, event)
            app.PlotParams.BudgetComponentsFlag = app.ShowcontrolvolumecomponentsSwitch.Value;
            UpdateAll(app) ;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OceanFluxBudgetToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {1020, 1020, 1020};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {1020, 1020};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {365, '1x', 706};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OceanFluxBudgetToolUIFigure = uifigure('Visible', 'off');
            app.OceanFluxBudgetToolUIFigure.Name = 'OceanFluxBudget Tool';
            app.OceanFluxBudgetToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OceanFluxBudgetToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PropertyTimeseriesPanel = uipanel(app.LeftPanel);
            app.PropertyTimeseriesPanel.Title = 'Property Timeseries';
            app.ReinitializeButton = uibutton(app.PropertyTimeseriesPanel, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Reinitialize';
            app.BalancecontrolvolumemassLabel = uilabel(app.PropertyTimeseriesPanel);
            app.BalancecontrolvolumemassLabel.Text = {'Balance '; 'control volume'; 'mass'};
            app.BalancecontrolvolumemassSwitch = uiswitch(app.PropertyTimeseriesPanel, 'slider');
            app.BalancecontrolvolumemassSwitch.ValueChangedFcn = createCallbackFcn(app, @BalancecontrolvolumemassSwitchValueChanged, true);
            app.BalancecontrolvolumemassSwitch.Tooltip = {'Adjust East Fram Strait (WSC) flux to give zero mass convergence'};
            app.BalancecontrolvolumemassSwitch.Value = 'On';
            app.ReferenceValuesPanel = uipanel(app.LeftPanel);
            app.ReferenceValuesPanel.Title = 'Reference Values';
            app.ReferenceSalinitygkgSpinnerLabel = uilabel(app.ReferenceValuesPanel);
            app.ReferenceSalinitygkgSpinnerLabel.Text = 'Reference Salinity [g/kg]';
            app.ReferenceSalinitygkgSpinner = uispinner(app.ReferenceValuesPanel);
            app.ReferenceSalinitygkgSpinner.Step = 0.2;
            app.ReferenceSalinitygkgSpinner.ValueChangingFcn = createCallbackFcn(app, @ReferenceSalinitygkgSpinnerValueChanging, true);
            app.ReferenceSalinitygkgSpinner.Limits = [0 40];
            app.ReferenceSalinitygkgSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceSalinitygkgSpinnerValueChanged, true);
            app.ReferenceSalinitygkgSpinner.Tooltip = {'Select value'};
            app.ReferenceSalinitygkgSpinner.Value = 34.8;
            app.ReferenceTemperatureCSpinnerLabel = uilabel(app.ReferenceValuesPanel);
            app.ReferenceTemperatureCSpinnerLabel.Text = 'Reference Temperature [C]';
            app.ReferenceTemperatureCSpinner = uispinner(app.ReferenceValuesPanel);
            app.ReferenceTemperatureCSpinner.Step = 0.5;
            app.ReferenceTemperatureCSpinner.ValueChangingFcn = createCallbackFcn(app, @ReferenceTemperatureCSpinnerValueChanging, true);
            app.ReferenceTemperatureCSpinner.Limits = [-2 10];
            app.ReferenceTemperatureCSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceTemperatureCSpinnerValueChanged, true);
            app.ReferenceTemperatureCSpinner.Tooltip = {'Select value'};
            app.DisplayOptionsPanel = uipanel(app.LeftPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.ShowmaxminSwitch_2Label = uilabel(app.DisplayOptionsPanel);
            app.ShowmaxminSwitch_2Label.Text = 'Show max/min';
            app.ShowmaxminSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowmaxminSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowmaxminSwitchValueChanged, true);
            app.ShowmaxminSwitch.Tooltip = {'Display maximum and minimum flux values'};
            app.ShowmaxminSwitch_2Label_2 = uilabel(app.DisplayOptionsPanel);
            app.ShowmaxminSwitch_2Label_2.Text = 'Show trend';
            app.ShowtrendSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowtrendSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowtrendSwitchValueChanged, true);
            app.ShowtrendSwitch.Tooltip = {'Display flux value trends'};
            app.FixaxesSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.FixaxesSwitchLabel.Text = 'Fix axes';
            app.FixaxesSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.FixaxesSwitch.ValueChangedFcn = createCallbackFcn(app, @FixaxesSwitchValueChanged, true);
            app.FixaxesSwitch.Tooltip = {'Fix y-axis ranges'};
            app.ShowbudgetcomponentsLabel = uilabel(app.DisplayOptionsPanel);
            app.ShowbudgetcomponentsLabel.Text = {'Show '; 'control volume'; 'components'};
            app.ShowcontrolvolumecomponentsSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowcontrolvolumecomponentsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowcontrolvolumecomponentsSwitchValueChanged, true);
            app.ShowcontrolvolumecomponentsSwitch.Tooltip = {'Show individual  components for control volume figures'};
            app.ComponentPanel = uipanel(app.LeftPanel);
            app.ComponentPanel.Title = 'Component';
            app.ComponentDropDown = uidropdown(app.ComponentPanel);
            app.ComponentDropDown.Items = {'West Fram Strait (EGC)', 'East Fram Strait (WSC)', 'BSO', 'Davis Strait', 'Bering Strait', 'R + P - E', 'Air/sea heat exchange'};
            app.ComponentDropDown.ItemsData = {'WestFramStrait', 'EastFramStrait', 'BSO', 'DavisStrait', 'BeringStrait', 'RpPmEStrait', 'AirSeaHeatFlux'};
            app.ComponentDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentDropDownValueChanged, true);
            app.ComponentDropDown.Tooltip = {'Pick the flux component to display'};
            app.ComponentDropDown.Value = 'WestFramStrait';
            app.ResourcesPanel = uipanel(app.LeftPanel);
            app.ResourcesPanel.Title = 'Resources';
            app.Hyperlink = uihyperlink(app.ResourcesPanel);
            app.Hyperlink.URL = 'https://github.com/ThomasHaine/Ocean-Flux-Budget';
            app.Hyperlink.Text = 'Github';
            app.Hyperlink_2 = uihyperlink(app.ResourcesPanel);
            app.Hyperlink_2.URL = 'https://github.com/ThomasHaine/Ocean-Flux-Budget/blob/main/WriteUp/budgets_theory.pdf';
            app.Hyperlink_2.Text = 'Theory notes';
            app.Hyperlink_3 = uihyperlink(app.ResourcesPanel);
            app.Hyperlink_3.URL = 'https://github.com/ThomasHaine/Ocean-Flux-Budget/blob/main/WriteUp/budgets_theory.pdf';
            app.Hyperlink_3.Text = 'YouTube';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SpeedFigure = uiaxes(app.CenterPanel);
            title(app.SpeedFigure, 'Speed [m/s]')
            ylabel(app.SpeedFigure, 'Speed [m/s]')
            zlabel(app.SpeedFigure, 'Z')
            app.SpeedFigure.YLim = [-0.1 0.1];
            app.SpeedFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.SalinityFigure = uiaxes(app.CenterPanel);
            title(app.SalinityFigure, 'Salinity [g/kg]')
            ylabel(app.SalinityFigure, 'Salinity [g/kg]')
            zlabel(app.SalinityFigure, 'Z')
            app.SalinityFigure.YLim = [30 40];
            app.SalinityFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.DensityFigure = uiaxes(app.CenterPanel);
            title(app.DensityFigure, 'Density [kg/m^3]')
            xlabel(app.DensityFigure, 'Time')
            ylabel(app.DensityFigure, 'Density [kg/m^3]')
            zlabel(app.DensityFigure, 'Z')
            app.DensityFigure.YLim = [1025 1030];
            app.DensityFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.LFCFluxFigure = uiaxes(app.CenterPanel);
            title(app.LFCFluxFigure, 'Liquid FW flux [km^3/yr]')
            xlabel(app.LFCFluxFigure, 'Time')
            ylabel(app.LFCFluxFigure, 'Liquid FW flux [km^3/yr]')
            zlabel(app.LFCFluxFigure, 'Z')
            app.LFCFluxFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.VolFluxFigure = uiaxes(app.CenterPanel);
            title(app.VolFluxFigure, 'Volume flux [Sv]')
            ylabel(app.VolFluxFigure, 'Volume flux [Sv]')
            zlabel(app.VolFluxFigure, 'Z')
            app.HeatFluxFigure = uiaxes(app.CenterPanel);
            title(app.HeatFluxFigure, 'Heat flux [TW]')
            ylabel(app.HeatFluxFigure, 'Heat flux [TW]')
            zlabel(app.HeatFluxFigure, 'Z')
            app.MassFluxFigure = uiaxes(app.CenterPanel);
            title(app.MassFluxFigure, 'Mass flux [Tg/s ~ Sv]')
            ylabel(app.MassFluxFigure, 'Mass flux [Tg/s]')
            zlabel(app.MassFluxFigure, 'Z')
            app.TemperatureFigure = uiaxes(app.CenterPanel);
            title(app.TemperatureFigure, 'Temperature [C]')
            ylabel(app.TemperatureFigure, 'Temperature [C]')
            zlabel(app.TemperatureFigure, 'Z')
            app.TemperatureFigure.YLim = [-2 8];
            app.TemperatureFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.SaltFluxFigure = uiaxes(app.CenterPanel);
            title(app.SaltFluxFigure, 'Salt flux [Gg/s]')
            ylabel(app.SaltFluxFigure, 'Salt flux [Gg/s]')
            zlabel(app.SaltFluxFigure, 'Z')
            app.PropertiesLabel = uilabel(app.CenterPanel);
            app.PropertiesLabel.Text = 'Properties';
            app.FluxesLabel = uilabel(app.CenterPanel);
            app.FluxesLabel.Text = 'Fluxes';
            app.CorrelationTable = uitable(app.CenterPanel);
            app.CorrelationTable.ColumnName = {'Speed correlation'; 'Property correlation'};
            app.CorrelationTable.RowName = {'Heat'; 'Salt'; 'Liquid FW'};
            app.ComponentLabel = uilabel(app.CenterPanel);
            app.ComponentLabel.Text = 'Component';
            app.RightPanel = uipanel(app.GridLayout);
            app.VolBudgetFigure = uiaxes(app.RightPanel);
            title(app.VolBudgetFigure, 'Volume [km^3]')
            ylabel(app.VolBudgetFigure, 'Volume [km^3]')
            zlabel(app.VolBudgetFigure, 'Z')
            app.HeatBudgetFigure = uiaxes(app.RightPanel);
            title(app.HeatBudgetFigure, 'Heat [ZJ]')
            ylabel(app.HeatBudgetFigure, 'Heat [J]')
            zlabel(app.HeatBudgetFigure, 'Z')
            app.LFCBudgetFigure = uiaxes(app.RightPanel);
            title(app.LFCBudgetFigure, 'Liquid FWC [km^3]')
            xlabel(app.LFCBudgetFigure, 'Time')
            ylabel(app.LFCBudgetFigure, 'Liquid FWC [km^3]')
            zlabel(app.LFCBudgetFigure, 'Z')
            app.AvgSalinityFigure = uiaxes(app.RightPanel);
            title(app.AvgSalinityFigure, 'Salinity [g/kg]')
            xlabel(app.AvgSalinityFigure, 'Time')
            ylabel(app.AvgSalinityFigure, 'Average salinity [g/kg]')
            zlabel(app.AvgSalinityFigure, 'Z')
            app.AvgTemperatureFigure = uiaxes(app.RightPanel);
            title(app.AvgTemperatureFigure, 'Temperature [C]')
            ylabel(app.AvgTemperatureFigure, 'Temperature [C]')
            zlabel(app.AvgTemperatureFigure, 'Z')
            app.MassBudgetFigure = uiaxes(app.RightPanel);
            title(app.MassBudgetFigure, 'Mass [Zg]')
            ylabel(app.MassBudgetFigure, 'Mass [Zg]')
            zlabel(app.MassBudgetFigure, 'Z')
            app.SaltBudgetFigure = uiaxes(app.RightPanel);
            title(app.SaltBudgetFigure, 'Salt mass [Pg]')
            ylabel(app.SaltBudgetFigure, 'Salt mass [Pg]')
            zlabel(app.SaltBudgetFigure, 'Z')
            app.BudgetAnomaliesLabel = uilabel(app.RightPanel);
            app.BudgetAnomaliesLabel.Text = 'Budget Anomalies';
            app.PropertiesLabel_2 = uilabel(app.RightPanel);
            app.PropertiesLabel_2.Text = 'Properties';
            app.TotalControlVolumeLabel = uilabel(app.RightPanel);
            app.TotalControlVolumeLabel.Text = 'Total Control Volume';
            % Show the figure after all components are created
            app.OceanFluxBudgetToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanFluxBudget

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OceanFluxBudgetToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OceanFluxBudgetToolUIFigure)
        end
    end
end